classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        q0SpinnerLabel                 matlab.ui.control.Label
        q0Spinner                      matlab.ui.control.Spinner
        q1SpinnerLabel                 matlab.ui.control.Label
        q1Spinner                      matlab.ui.control.Spinner
        q2SpinnerLabel                 matlab.ui.control.Label
        q2Spinner                      matlab.ui.control.Spinner
        q3SpinnerLabel                 matlab.ui.control.Label
        q3Spinner                      matlab.ui.control.Spinner
        q4SpinnerLabel                 matlab.ui.control.Label
        q4Spinner                      matlab.ui.control.Spinner
        q5SpinnerLabel                 matlab.ui.control.Label
        q5Spinner                      matlab.ui.control.Spinner
        q6SpinnerLabel                 matlab.ui.control.Label
        q6Spinner                      matlab.ui.control.Spinner
        StartButton                    matlab.ui.control.Button
        TeachmodeoveridesjointanglesLabel  matlab.ui.control.Label
        XSpinnerLabel                  matlab.ui.control.Label
        XSpinner                       matlab.ui.control.Spinner
        YSpinnerLabel                  matlab.ui.control.Label
        YSpinner                       matlab.ui.control.Spinner
        ZSpinnerLabel                  matlab.ui.control.Label
        ZSpinner                       matlab.ui.control.Spinner
        ManualEndeffectorControlLabel  matlab.ui.control.Label
        MoveItButton                   matlab.ui.control.Button
        EStopButton                    matlab.ui.control.Button
    end

    properties (Access = public)
        motion;
        sawyer1;
        table;
        bowl;
        mushroom;
        tomato;
        carrot;
        lettucs;
        onion;
        tomatosauce;
    end
    methods (Access = public)
        
        function results = init(app)
            floorOffset = (-1.0896/2);                                                  % measured height or table in body0.ply
            objectOffset = transl(0, 0 , -0.1);                                         % offset to lower endeffector onto object
            workSpace = [-2.5 2.5 -2.5 2.5 (2*floorOffset) 2];
            sawyerBase = transl(0,0,0);
            axis(workSpace);
            hold on;
            
            sawyerBase              = transl(0,0,0);
            sawywerInit             = transl(0.2, 0, 1.2);
            bowlBaseCoord           = transl(-0.4, 0.5, 0);
            mushroomBaseCoord       = transl(0.6, -0.4, 0.0);
            tomatoBaseCoord         = transl(0.6, 0.0, 0.0);
            carrotBaseCoord         = transl(0.6, 0.4, 0.0);
            lettuceBaseCoord        = transl(0.4, 0.5, 0.0);
            onionBaseCoord          = transl(0.0, 0.6, 0.0);
            tomatoSauceBaseCoord    = transl(-0.2, 0.5 ,0.0);
            
            % Init Sawyer
            app.motion      = move();
            app.sawyer1     = sawyer(workSpace, 1, sawyerBase);
            % Init bodies
            %             app.table       = body(workSpace, 'table', transl(0,0,floorOffset));            % Dimensions of the table (x, y, z) = (1.4880, 2.3383, 1.0896)
            %             app.bowl        = body(workSpace, 'Bowl', transl(0,0,0));
            %             app.mushroom    = body(workSpace, 'mushroom', transl(0.1,0,0));
            %             app.tomato      = body(workSpace, 'tomato', transl(0.2,0,0));
            %             app.carrot      = body(workSpace, 'carrot', transl(0.1,0.1,0));
            %             app.lettuce     = body(workSpace, 'lettuce', transl(0.2,0.1,0));
            %             app.onion       = body(workSpace, 'onion', transl(0.15,0.15,0));
            %             app.tomatosauce = body(workSpace, 'tomatosauce', transl(0.2,0.15,0));
            app.motion.rmrcToPointFromCurrent(app.sawyer1, bowlBaseCoord);
            
            pause(0.01);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: q0Spinner
        function q0SpinnerValueChanged(app, event)
            value = app.q0Spinner.Value;
            app.sawyer1.currentJoints(1) = deg2rad(value);
            app.sawyer1.model.plot3d(app.sawyer1.currentJoints,'workspace',app.sawyer1.workspace,'floorlevel', 0);
            pause(0.001);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.init();
        end

        % Value changed function: q1Spinner
        function q1SpinnerValueChanged(app, event)
            value = app.q1Spinner.Value;
            app.sawyer1.currentJoints(2) = deg2rad(value);
            app.sawyer1.model.plot3d(app.sawyer1.currentJoints,'workspace',app.sawyer1.workspace,'floorlevel', 0);
            pause(0.001);
        end

        % Value changed function: q2Spinner
        function q2SpinnerValueChanged(app, event)
            value = app.q2Spinner.Value;
            app.sawyer1.currentJoints(3) = deg2rad(value);
            app.sawyer1.model.plot3d(app.sawyer1.currentJoints,'workspace',app.sawyer1.workspace,'floorlevel', 0);
            pause(0.001);
        end

        % Value changed function: q3Spinner
        function q3SpinnerValueChanged(app, event)
            value = app.q3Spinner.Value;
            app.sawyer1.currentJoints(4) = deg2rad(value);
            app.sawyer1.model.plot3d(app.sawyer1.currentJoints,'workspace',app.sawyer1.workspace,'floorlevel', 0);
            pause(0.001);
        end

        % Value changed function: q4Spinner
        function q4SpinnerValueChanged(app, event)
            value = app.q4Spinner.Value;
            app.sawyer1.currentJoints(5) = deg2rad(value);
            app.sawyer1.model.plot3d(app.sawyer1.currentJoints,'workspace',app.sawyer1.workspace,'floorlevel', 0);
            pause(0.001);
        end

        % Value changed function: q5Spinner
        function q5SpinnerValueChanged(app, event)
            value = app.q5Spinner.Value;
            app.sawyer1.currentJoints(6) = deg2rad(value);
            app.sawyer1.model.plot3d(app.sawyer1.currentJoints,'workspace',app.sawyer1.workspace,'floorlevel', 0);
            pause(0.001);
        end

        % Value changed function: q6Spinner
        function q6SpinnerValueChanged(app, event)
            value = app.q6Spinner.Value;
            app.sawyer1.currentJoints(7) = deg2rad(value);
            app.sawyer1.model.plot3d(app.sawyer1.currentJoints,'workspace',app.sawyer1.workspace,'floorlevel', 0);
            pause(0.001);
        end

        % Value changed function: XSpinner
        function XSpinnerValueChanged(app, event)
            value = app.XSpinner.Value;
        end

        % Value changed function: YSpinner
        function YSpinnerValueChanged(app, event)
            value = app.YSpinner.Value;
        end

        % Value changed function: ZSpinner
        function ZSpinnerValueChanged(app, event)
            value = app.ZSpinner.Value;
        end

        % Button pushed function: MoveItButton
        function MoveItButtonPushed(app, event)
            targetPose = transl(app.XSpinner.Value, app.YSpinner.Value, app.ZSpinner.Value);
            app.motion.moveRobot(app.sawyer1, targetPose, 0);
        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
            %error('E-Stop Activated. Program terminated.');
            %quit("force");
            disp('E-stop pressed');
            pause;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.q0SpinnerLabel = uilabel(app.UIFigure);
            app.q0SpinnerLabel.Text = 'q0';
            app.q0Spinner = uispinner(app.UIFigure);
            app.q0Spinner.ValueChangedFcn = createCallbackFcn(app, @q0SpinnerValueChanged, true);
            app.q1SpinnerLabel = uilabel(app.UIFigure);
            app.q1SpinnerLabel.Text = 'q1';
            app.q1Spinner = uispinner(app.UIFigure);
            app.q1Spinner.ValueChangedFcn = createCallbackFcn(app, @q1SpinnerValueChanged, true);
            app.q2SpinnerLabel = uilabel(app.UIFigure);
            app.q2SpinnerLabel.Text = 'q2';
            app.q2Spinner = uispinner(app.UIFigure);
            app.q2Spinner.ValueChangedFcn = createCallbackFcn(app, @q2SpinnerValueChanged, true);
            app.q3SpinnerLabel = uilabel(app.UIFigure);
            app.q3SpinnerLabel.Text = 'q3';
            app.q3Spinner = uispinner(app.UIFigure);
            app.q3Spinner.ValueChangedFcn = createCallbackFcn(app, @q3SpinnerValueChanged, true);
            app.q4SpinnerLabel = uilabel(app.UIFigure);
            app.q4SpinnerLabel.Text = 'q4';
            app.q4Spinner = uispinner(app.UIFigure);
            app.q4Spinner.ValueChangedFcn = createCallbackFcn(app, @q4SpinnerValueChanged, true);
            app.q5SpinnerLabel = uilabel(app.UIFigure);
            app.q5SpinnerLabel.Text = 'q5';
            app.q5Spinner = uispinner(app.UIFigure);
            app.q5Spinner.ValueChangedFcn = createCallbackFcn(app, @q5SpinnerValueChanged, true);
            app.q6SpinnerLabel = uilabel(app.UIFigure);
            app.q6SpinnerLabel.Text = 'q6';
            app.q6Spinner = uispinner(app.UIFigure);
            app.q6Spinner.ValueChangedFcn = createCallbackFcn(app, @q6SpinnerValueChanged, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TeachmodeoveridesjointanglesLabel = uilabel(app.UIFigure);
            app.TeachmodeoveridesjointanglesLabel.Text = 'Teach mode (overides joint angles)';
            app.XSpinnerLabel = uilabel(app.UIFigure);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.UIFigure);
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.YSpinnerLabel = uilabel(app.UIFigure);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.UIFigure);
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @YSpinnerValueChanged, true);
            app.ZSpinnerLabel = uilabel(app.UIFigure);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.UIFigure);
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @ZSpinnerValueChanged, true);
            app.ManualEndeffectorControlLabel = uilabel(app.UIFigure);
            app.ManualEndeffectorControlLabel.Text = 'Manual Endeffector Control';
            app.MoveItButton = uibutton(app.UIFigure, 'push');
            app.MoveItButton.ButtonPushedFcn = createCallbackFcn(app, @MoveItButtonPushed, true);
            app.MoveItButton.Text = 'Move It!';
            app.EStopButton = uibutton(app.UIFigure, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Text = 'E-Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end